classdef CassieAnimation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        TimeEditFieldLabel            matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.NumericEditField
        PlayButton                    matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        PlaySpeedDropDownLabel        matlab.ui.control.Label
        PlaySpeedDropDown             matlab.ui.control.DropDown
        NextFrameButton               matlab.ui.control.Button
        PreviousFrameButton           matlab.ui.control.Button
        FrameIntervalsEditFieldLabel  matlab.ui.control.Label
        FrameIntervalsEditField       matlab.ui.control.NumericEditField
        CameraFollowCheckBox          matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        X_states
        T_length
        T
        StopPlay = 0;
        TorsoSize = 0.05*[2,1,3];
        PlaySpeed = 1;
        index = 1;
        FrameInterval = 100;
        CameraFollow = 0;
        
        h1
        h2
        h3
        h4
        h5
        
        TO1
        TO2
        TO3
        TO4
        TO5
        TO6
    end
    
    
    methods (Access = public)
        
        function Initializaztion(app,X_states,T_states)
            app.X_states = X_states;
            app.T = T_states;
            app.T_length = size(app.T,1);
            app.Slider.Limits = [0.1 app.T_length];
            app.PlaySpeedDropDown.ItemsData = [1, 1/2, 1/4, 1/8, 1/16, 1/32, 1/64, 1/128];
            app.CameraFollowCheckBox.Value = app.CameraFollow;
            app.FrameIntervalsEditField.Value = app.FrameInterval*0.0005;
            NewPlot(app,app.X_states(:,1));
        end
        
        function UpdatePlot(app,q)
            [LeftLegCoordinate, RightLegCoordinate, LeftFeetCoordinate, RightFeetCoordinate, ConnectionCoordinate] = GetCassiePlotCoordinate(q,0);
            [vertex_set1, vertex_set2, vertex_set3, vertex_set4, vertex_set5, vertex_set6] = GetCubeVertexCoordinate(q(1:3),q(4:6),app.TorsoSize);
            set(app.h1,'XData',LeftLegCoordinate(1,:),'YData',LeftLegCoordinate(2,:),'ZData',LeftLegCoordinate(3,:));
            set(app.h2,'XData',RightLegCoordinate(1,:),'YData',RightLegCoordinate(2,:),'ZData',RightLegCoordinate(3,:));
            set(app.h3,'XData',LeftFeetCoordinate(1,:),'YData',LeftFeetCoordinate(2,:),'ZData',LeftFeetCoordinate(3,:));
            set(app.h4,'XData',RightFeetCoordinate(1,:),'YData',RightFeetCoordinate(2,:),'ZData',RightFeetCoordinate(3,:));
            set(app.h5,'XData',ConnectionCoordinate(1,:),'YData',ConnectionCoordinate(2,:),'ZData',ConnectionCoordinate(3,:));
            set(app.TO1,'XData',vertex_set1(1,:),'YData',vertex_set1(2,:),'ZData',vertex_set1(3,:));
            set(app.TO2,'XData',vertex_set2(1,:),'YData',vertex_set2(2,:),'ZData',vertex_set2(3,:));
            set(app.TO3,'XData',vertex_set3(1,:),'YData',vertex_set3(2,:),'ZData',vertex_set3(3,:));
            set(app.TO4,'XData',vertex_set4(1,:),'YData',vertex_set4(2,:),'ZData',vertex_set4(3,:));
            set(app.TO5,'XData',vertex_set5(1,:),'YData',vertex_set5(2,:),'ZData',vertex_set5(3,:));
            set(app.TO6,'XData',vertex_set6(1,:),'YData',vertex_set6(2,:),'ZData',vertex_set6(3,:));
            if app.CameraFollow == 1
                axis([q(1)-1, q(1)+1, q(2)-1, q(2)+1, -0.01, 2]);
            end
            drawnow;
        end
        
        function NewPlot(app,q)
            linkwidth = 2;
            Torso_Color = 0.5*[1, 1, 1];
            [LeftLegCoordinate, RightLegCoordinate, LeftFeetCoordinate, RightFeetCoordinate, ConnectionCoordinate] = GetCassiePlotCoordinate(q,0);
            [vertex_set1, vertex_set2, vertex_set3, vertex_set4, vertex_set5, vertex_set6] = GetCubeVertexCoordinate(q(1:3),q(4:6),app.TorsoSize);
            figure(1000)
            cla;
            hold on;
            % Leg
            app.h1 = plot3(LeftLegCoordinate(1,:),LeftLegCoordinate(2,:),LeftLegCoordinate(3,:),'Color','r','LineWidth',linkwidth);
            app.h2 = plot3(RightLegCoordinate(1,:),RightLegCoordinate(2,:),RightLegCoordinate(3,:),'Color','b','LineWidth',linkwidth);
            app.h3 = plot3(LeftFeetCoordinate(1,:),LeftFeetCoordinate(2,:),LeftFeetCoordinate(3,:),'Color','r','LineWidth',linkwidth);
            app.h4 = plot3(RightFeetCoordinate(1,:),RightFeetCoordinate(2,:),RightFeetCoordinate(3,:),'Color','b','LineWidth',linkwidth);
            app.h5 = plot3(ConnectionCoordinate(1,:),ConnectionCoordinate(2,:),ConnectionCoordinate(3,:),'Color','k','LineWidth',linkwidth);
            % Torso
            app.TO1 = patch(vertex_set1(1,:),vertex_set1(2,:),vertex_set1(3,:),Torso_Color);
            app.TO2 = patch(vertex_set2(1,:),vertex_set2(2,:),vertex_set2(3,:),Torso_Color);
            app.TO3 = patch(vertex_set3(1,:),vertex_set3(2,:),vertex_set3(3,:),Torso_Color);
            app.TO4 = patch(vertex_set4(1,:),vertex_set4(2,:),vertex_set4(3,:),Torso_Color);
            app.TO5 = patch(vertex_set5(1,:),vertex_set5(2,:),vertex_set5(3,:),Torso_Color);
            app.TO6 = patch(vertex_set6(1,:),vertex_set6(2,:),vertex_set6(3,:),Torso_Color);
            % ground
            ground_size = 10;
            patch(ground_size*[1,1,-1,-1],ground_size*[1,-1,-1,1],[0,0,0,0],[0,1,0],'FaceAlpha',0.1);
            hold off;
            axis equal
            axis([q(1)-1, q(1)+1, q(2)-1, q(2)+1, -0.01, 2]);
            view([1 1 1])
            grid on
        end
        
        function UpdateGUI(app,index)
            app.TimeEditField.Value = app.T(index);
            app.Slider.Value = index;
        end
        
        function index = GetIndexFromTime(app,t)
            t = median([t,app.T(1),app.T(end)]);
            percent = (t-app.T(1)) /(app.T(end)-app.T(1));
            index = ceil(percent*app.T_length);
        end
    end
    

    methods (Access = private)

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            %             index = ceil(changingValue*app.T_length);
            %             index = median([index,1,app.T_length]);
            app.index = ceil(changingValue);
            UpdatePlot(app,app.X_states(:,app.index));
            UpdateGUI(app,app.index);
            app.StopPlay = 1;
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
            app.index = GetIndexFromTime(app,value);
            UpdatePlot(app,app.X_states(:,app.index));
            UpdateGUI(app,app.index);
            app.StopPlay = 1;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            tic;
            sim_previous_time = app.TimeEditField.Value;
            sim_current_time = 0;
            real_current_time = 0;
            real_previous_time = 0;
            real_time_interval = 0;
            start_time = app.TimeEditField.Value;
            app.StopPlay = 0;
            while sim_current_time <app.T(end) && app.StopPlay == 0;
                real_current_time = toc;
                real_time_interval = real_current_time - real_previous_time;
                real_previous_time = real_current_time;
                sim_current_time = sim_previous_time + real_time_interval*app.PlaySpeed;
                sim_previous_time = sim_current_time;
                app.index = app.GetIndexFromTime(sim_current_time);
                UpdatePlot(app,app.X_states(:,app.index));
                UpdateGUI(app,app.index);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopPlay = 1;
        end

        % Value changed function: PlaySpeedDropDown
        function PlaySpeedDropDownValueChanged(app, event)
            value = app.PlaySpeedDropDown.Value;
            app.PlaySpeed = value;
        end

        % Value changed function: FrameIntervalsEditField
        function FrameIntervalsEditFieldValueChanged(app, event)
            value = app.FrameIntervalsEditField.Value;
            app.FrameInterval = floor(value/0.0005);
            app.FrameIntervalsEditField.Value = app.FrameInterval*0.0005;
        end

        % Button pushed function: PreviousFrameButton
        function PreviousFrameButtonPushed(app, event)
            app.index = app.index - app.FrameInterval;
            app.index = max([app.index,1]);
            UpdatePlot(app,app.X_states(:,app.index));
            UpdateGUI(app,app.index);
            app.StopPlay = 1;
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            app.index = app.index + app.FrameInterval;
            app.index = min([app.index,app.T_length]);
            UpdatePlot(app,app.X_states(:,app.index));
            UpdateGUI(app,app.index);
            app.StopPlay = 1;
        end

        % Value changed function: CameraFollowCheckBox
        function CameraFollowCheckBoxValueChanged(app, event)
            value = app.CameraFollowCheckBox.Value;
            app.CameraFollow = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%.4f';
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlaySpeedDropDownLabel = uilabel(app.UIFigure);
            app.PlaySpeedDropDownLabel.Text = 'PlaySpeed';
            app.PlaySpeedDropDown = uidropdown(app.UIFigure);
            app.PlaySpeedDropDown.Items = {'1', '1/2', '1/4', '1/8', '1/16', '1/32', '1/64', '1/128'};
            app.PlaySpeedDropDown.ItemsData = {'1', '1/2', '1/4', '1/8', '1/16', '1/32', '1/64', '1/128'};
            app.PlaySpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @PlaySpeedDropDownValueChanged, true);
            app.PlaySpeedDropDown.Value = '1';
            app.NextFrameButton = uibutton(app.UIFigure, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame';
            app.PreviousFrameButton = uibutton(app.UIFigure, 'push');
            app.PreviousFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFrameButtonPushed, true);
            app.PreviousFrameButton.Text = 'Previous Frame';
            app.FrameIntervalsEditFieldLabel = uilabel(app.UIFigure);
            app.FrameIntervalsEditFieldLabel.Text = 'Frame Interval (s)';
            app.FrameIntervalsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @FrameIntervalsEditFieldValueChanged, true);
            app.FrameIntervalsEditField.Value = 0.0005;
            app.CameraFollowCheckBox = uicheckbox(app.UIFigure);
            app.CameraFollowCheckBox.ValueChangedFcn = createCallbackFcn(app, @CameraFollowCheckBoxValueChanged, true);
            app.CameraFollowCheckBox.Text = 'CameraFollow';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CassieAnimation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end